classdef IHM_PL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HMIUIFigure                 matlab.ui.Figure
        LaunchButton                matlab.ui.control.Button
        SimulationspeedSliderLabel  matlab.ui.control.Label
        SimulationspeedSlider       matlab.ui.control.Slider
        SimulationrunningLamp       matlab.ui.control.Lamp
        TextArea                    matlab.ui.control.TextArea
        AttackScenariosListBox      matlab.ui.control.ListBox
        Gauge                       matlab.ui.control.LinearGauge
        ParametersButton            matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        FaultsButton                matlab.ui.control.Button
        AttacksButton               matlab.ui.control.Button
        FaultScenariosListBox       matlab.ui.control.ListBox
        Attack_startLabel           matlab.ui.control.Label
        Attack_startSlider          matlab.ui.control.Slider
        Attack_endSliderLabel       matlab.ui.control.Label
        Attack_endSlider            matlab.ui.control.Slider
    end

    properties (Access = public)
        ScenarioChoice = '1';
    end
    
    properties (Access = private)
        DialogApp % Options dialog app
        Qf1 = 1e-4;
        Qf2 = 1e-4;
        Qof1 = 1e-4;
        h0 = 0;
        SimuTime = 250;
        Attack = 0;
    end

    methods (Access = public)
    
        function UnlockButtons(app)
            app.LaunchButton.Enable = 'on';
            app.ParametersButton.Enable = 'on';
        end
        function UpdateValues(app,Qf1_2,Qf2_2,Qof1_2,h0_2,SimuTime_2)
            app.Qf1=Qf1_2;
            app.Qf2=Qf2_2;
            app.Qof1=Qof1_2;
            app.h0=h0_2;
            app.SimuTime=SimuTime_2;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gauge.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.Gauge.Limits = [0 app.SimuTime];
            app.AttackScenariosListBox.Visible = 'off';
            app.AttackScenariosListBox.Enable = 'off';
            app.FaultsButton.Enable = 'off';
            app.FaultsButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)

            app.LaunchButton.Enable = 'off';
            app.ParametersButton.Enable = 'off';
            if app.Attack == 1
                app.FaultsButton.Enable = 'off';
                app.AttackScenariosListBox.Enable = 'off';
                app.ScenarioChoice = app.AttackScenariosListBox.Value;
                
            else
                app.AttacksButton.Enable = 'off';
                app.FaultScenariosListBox.Enable = 'off';
                app.ScenarioChoice = app.FaultScenariosListBox.Value;  
                app.Attack_startSlider.Visible='on';
                app.Attack_endSlider.Visible='on';
                
            end
            app.TextArea.Value = 'Simulation running';
            app.TextArea.Visible = 'on';
            app.SimulationrunningLamp.Color = 'red';
            %set_param('CHEM_FULL_BENCHMARK', 'SimulationCommand', 'start')
            sim('CHEM_FULL_BENCHMARK');
            app.Gauge.Limits = [0 app.SimuTime];
            app.Gauge.Visible = 'on';
            app.Gauge.Visible = 'off';
            app.Gauge.Value = 0;
            app.TextArea.Visible = 'off';
            app.SimulationrunningLamp.Color = 'green';
            if app.Attack == 1
                app.AttackScenariosListBox.Enable = 'on';
                app.FaultsButton.Enable = 'on';
            else
                app.FaultScenariosListBox.Enable = 'on';
                app.AttacksButton.Enable = 'on';
            end
            app.LaunchButton.Enable = 'on';
            app.ParametersButton.Enable = 'on';
            
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            app.LaunchButton.Enable = 'off';
            app.ParametersButton.Enable = 'off';
            app.DialogApp = Options(app, app.Qf1, app.Qf2, app.Qof1, app.h0 ,app.SimuTime);
        end

        % Close request function: HMIUIFigure
        function HMIUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: FaultsButton
        function FaultsButtonPushed(app, event)
            app.FaultsButton.Enable = 'off';
            app.FaultsButton.BackgroundColor = [0 1 0];
            app.AttacksButton.BackgroundColor = [0.96 0.96 0.96];
            app.AttackScenariosListBox.Visible = 'off';
            app.AttackScenariosListBox.Enable = 'off';
            app.FaultScenariosListBox.Visible = 'on';
            app.FaultScenariosListBox.Enable = 'on';
            app.Attack = 0;
            app.AttacksButton.Enable = 'on';
        end

        % Button pushed function: AttacksButton
        function AttacksButtonPushed(app, event)
            app.AttacksButton.Enable = 'off';
            app.AttacksButton.BackgroundColor = [0 1 0];
            app.FaultsButton.BackgroundColor = [0.96 0.96 0.96];
            app.FaultScenariosListBox.Visible = 'off';
            app.FaultScenariosListBox.Enable = 'off';
            app.AttackScenariosListBox.Visible = 'on';
            app.AttackScenariosListBox.Enable = 'on';
            app.Attack = 1;
            app.FaultsButton.Enable = 'on';
        end

        % Value changed function: FaultScenariosListBox
        function updateFaultScenario(app, event)
            value = str2double(app.FaultScenariosListBox.Value);
            if value == 5 || value == 6
                app.Attack_startSlider.Visible='on';
                app.Attack_endSlider.Visible='on';
                app.Attack_endSliderLabel.Visible = 'on';
                app.Attack_startLabel.Visible = 'on';
            else
                app.Attack_startSlider.Visible='off';
                app.Attack_endSlider.Visible='off';
                app.Attack_endSliderLabel.Visible = 'off';
                app.Attack_startLabel.Visible = 'off';
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HMIUIFigure = uifigure('Visible', 'off');
            app.HMIUIFigure.Name = 'HMI';
            app.HMIUIFigure.CloseRequestFcn = createCallbackFcn(app, @HMIUIFigureCloseRequest, true);
            app.LaunchButton = uibutton(app.HMIUIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.SimulationspeedSliderLabel = uilabel(app.HMIUIFigure);
            app.SimulationspeedSliderLabel.Text = 'Simulation speed';
            app.SimulationspeedSlider = uislider(app.HMIUIFigure);
            app.SimulationspeedSlider.Limits = [1 40];
            app.SimulationspeedSlider.Value = 40;
            app.SimulationrunningLamp = uilamp(app.HMIUIFigure);
            app.TextArea = uitextarea(app.HMIUIFigure);
            app.TextArea.Editable = 'off';
            app.AttackScenariosListBox = uilistbox(app.HMIUIFigure);
            app.AttackScenariosListBox.Items = {'14) T1 short water theft', '15) T1 short water theft my1 blocked', '16) T1 long water theft my1 blocked', '17) T1 long water theft my1 slowly changed', '18) T2 short water theft', '19) T1 short water theft my2 blocked', '20) T2 long water theft my2 blocked', '21) T2 long water theft my2 slowly changed', '23) T1 overflow with simulated leak', '24) T1 overflow with PI pass by', '25) Emptying T2 without leak', '26) Theft when user is not pumping water', '27) Theft when user is not pumping water', ' same rate'};
            app.AttackScenariosListBox.ItemsData = {'14', '15', '16', '17', '18', '19', '20', '21', '23', '24', '25', '26', '27'};
            app.AttackScenariosListBox.Value = '14';
            app.Gauge = uigauge(app.HMIUIFigure, 'linear');
            app.Gauge.Limits = [0 250];
            app.ParametersButton = uibutton(app.HMIUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.CloseButton = uibutton(app.HMIUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.FaultsButton = uibutton(app.HMIUIFigure, 'push');
            app.FaultsButton.ButtonPushedFcn = createCallbackFcn(app, @FaultsButtonPushed, true);
            app.FaultsButton.Text = 'Faults';
            app.AttacksButton = uibutton(app.HMIUIFigure, 'push');
            app.AttacksButton.ButtonPushedFcn = createCallbackFcn(app, @AttacksButtonPushed, true);
            app.AttacksButton.Text = 'Attacks';
            app.FaultScenariosListBox = uilistbox(app.HMIUIFigure);
            app.FaultScenariosListBox.Items = {'1) Faultless', '2) Pump fault', '3) T1 level sensor fault', '4) T2 level sensor fault', '5) T1 leak', '6) T2 leak', '7) Sensor fault mUp', '8) Valve Vb stuck closed', '9) Sensor fault mUb', '10) Sensor fault Qp', '11) PI controller KO', '12) Valve Vb stuck open', '13) T1 sensor fault (not 0)'};
            app.FaultScenariosListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.FaultScenariosListBox.ValueChangedFcn = createCallbackFcn(app, @updateFaultScenario, true);
            app.FaultScenariosListBox.Value = '1';
            app.Attack_startLabel = uilabel(app.HMIUIFigure);
            app.Attack_startLabel.Visible = 'off';
            app.Attack_startLabel.Text = 'Attack_start';
            app.Attack_startSlider = uislider(app.HMIUIFigure);
            app.Attack_startSlider.Limits = [0 250];
            app.Attack_startSlider.Visible = 'off';
            app.Attack_endSliderLabel = uilabel(app.HMIUIFigure);
            app.Attack_endSliderLabel.Visible = 'off';
            app.Attack_endSliderLabel.Text = 'Attack_end';
            app.Attack_endSlider = uislider(app.HMIUIFigure);
            app.Attack_endSlider.Limits = [0 250];
            app.Attack_endSlider.Visible = 'off';
            % Show the figure after all components are created
            app.HMIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHM_PL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HMIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HMIUIFigure)
        end
    end
end