classdef Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParametersUIFigure            matlab.ui.Figure
        FlowleakfromT1m3sEditFieldLabel  matlab.ui.control.Label
        FlowleakfromT1m3sEditField    matlab.ui.control.NumericEditField
        SaveChangesButton             matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        FlowleakfromT2m3sEditFieldLabel  matlab.ui.control.Label
        FlowleakfromT2m3sEditField    matlab.ui.control.NumericEditField
        OverflowrateinT1m3sEditFieldLabel  matlab.ui.control.Label
        OverflowrateinT1m3sEditField  matlab.ui.control.NumericEditField
        InitialwaterlevelinT206EditFieldLabel  matlab.ui.control.Label
        InitialwaterlevelinT206EditField  matlab.ui.control.NumericEditField
        SimulationTime200EditFieldLabel  matlab.ui.control.Label
        SimulationTime200EditField    matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        CallingApp   % Main app
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Qf1, Qf2, Qof1, h0, SimuTime)
            app.CallingApp = mainapp;
            app.FlowleakfromT1m3sEditField.Value=Qf1;
            app.FlowleakfromT2m3sEditField.Value=Qf2;
            app.OverflowrateinT1m3sEditField.Value=Qof1;
            app.InitialwaterlevelinT206EditField.Value=h0;
            app.SimulationTime200EditField.Value=SimuTime;
        end

        % Close request function: ParametersUIFigure
        function ParametersUIFigureCloseRequest(app, event)
            UnlockButtons(app.CallingApp)
            delete(app)
            
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            Variables_Update
            UnlockButtons(app.CallingApp)
            UpdateValues(app.CallingApp, app.FlowleakfromT1m3sEditField.Value, app.FlowleakfromT2m3sEditField.Value, app.OverflowrateinT1m3sEditField.Value, app.InitialwaterlevelinT206EditField.Value, app.SimulationTime200EditField.Value)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UnlockButtons(app.CallingApp)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParametersUIFigure = uifigure;
            app.ParametersUIFigure.Name = 'Parameters';
            app.ParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParametersUIFigureCloseRequest, true);
            app.FlowleakfromT1m3sEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.FlowleakfromT1m3sEditFieldLabel.Text = 'Flow leak from T1[m^3/s]';
            app.FlowleakfromT1m3sEditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.FlowleakfromT1m3sEditField.ValueDisplayFormat = '%e';
            app.FlowleakfromT1m3sEditField.Value = 0.0001;
            app.SaveChangesButton = uibutton(app.ParametersUIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.CancelButton = uibutton(app.ParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FlowleakfromT2m3sEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.FlowleakfromT2m3sEditFieldLabel.Text = 'Flow leak from T2[m^3/s]';
            app.FlowleakfromT2m3sEditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.FlowleakfromT2m3sEditField.ValueDisplayFormat = '%e';
            app.FlowleakfromT2m3sEditField.Value = 0.0001;
            app.OverflowrateinT1m3sEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.OverflowrateinT1m3sEditFieldLabel.Text = 'Overflow rate in T1[m^3/s]';
            app.OverflowrateinT1m3sEditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.OverflowrateinT1m3sEditField.ValueDisplayFormat = '%e';
            app.OverflowrateinT1m3sEditField.Value = 0.0001;
            app.InitialwaterlevelinT206EditFieldLabel = uilabel(app.ParametersUIFigure);
            app.InitialwaterlevelinT206EditFieldLabel.Text = 'Initial water level in T2 (<0.6)';
            app.InitialwaterlevelinT206EditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.InitialwaterlevelinT206EditField.Limits = [0 0.6];
            app.SimulationTime200EditFieldLabel = uilabel(app.ParametersUIFigure);
            app.SimulationTime200EditFieldLabel.Text = 'Simulation Time (>200)';
            app.SimulationTime200EditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.SimulationTime200EditField.Limits = [200 Inf];
            app.SimulationTime200EditField.Value = 250;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Options(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametersUIFigure)
        end
    end
end